classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartButton             matlab.ui.control.Button
        LoadnetButton           matlab.ui.control.Button
        OriginalImageLabel      matlab.ui.control.Label
        SegmentedImageLabel     matlab.ui.control.Label
        PauseButton             matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        CharacterTextAreaLabel  matlab.ui.control.Label
        CharacterTextArea       matlab.ui.control.TextArea
        ClearButton             matlab.ui.control.Button
        SentenceTextAreaLabel   matlab.ui.control.Label
        SentenceTextArea        matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
clear('cam');

% load("Gfinal.mat");
net = evalin('base','net');
url = 'http://192.168.43.242:8080/shot.jpg';
last = " ";
while(1)
txt = evalin('base','txt');
% last = evalin('base','last');

% img = snapshot(cam);
 img = imread(url);
var=224;
im=imresize(img,[var var]);
imwrite(im,'hand.jpg');
[out bin] = generate_skinmap('hand.jpg');

im=imread('hand.jpg');
bin=uint8(bin);
rg(:,:,1)=im(:,:,1).*bin(:,:);
rg(:,:,2)=im(:,:,2).*bin(:,:);
rg(:,:,3)=im(:,:,3).*bin(:,:);


imshow(im,'parent',app.UIAxes);
imshow(rg,'parent',app.UIAxes2);


x = string(classify(net.net,im));
app.CharacterTextArea.Value = x;
if(x ~= last)
    last = x;
    count = 0;
else
    count = count +1
    if count == 5
        if(last == "space")
            txt = txt + " ";
        elseif(last == "not")
            continue
        elseif(last == "del")
            txt = char(txt);
            txt = txt(1:end-1);
            txt = string(txt);
        else
        txt = txt + x;
        end
        assignin("base","txt",txt);
        app.SentenceTextArea.Value = txt;
        count = 0;        
    end
end
pause(0.1);
end


        end

        % Callback function
        function wordEditFieldValueChanged(app, event)
            value = app.wordEditField.Value;
            disp(x)
        end

        % Callback function
        function ImageClicked(app, event)
         
        end

        % Callback function
        function Image_2Clicked(app, event)
          
        end

        % Button pushed function: LoadnetButton
        function LoadnetButtonPushed(app, event)
           %net = load("Gfinal2.mat");
           assignin('base',"net",load("Gfinal4.mat"));
           app.CharacterTextArea.Value = "Net Loaded";
           assignin('base','txt'," ");
%            assignin('base','last'," ");
           
           
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
             app.CharacterTextArea.Value = "Paused";
             pause();
        end

        % Callback function
        function ImageClicked2(app, event)
            imshow(rg,'parent',app.Image);
        end

        % Value changed function: CharacterTextArea
        function CharacterTextAreaValueChanged(app, event)
            value = app.CharacterTextArea.Value;
            disp(value);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
            assignin('base','txt'," ");
%             assignin('base','last'," ");
%             clear('txt');
%             clear('last');
            app.CharacterTextArea.Value = "Cleared";
            app.SentenceTextArea.Value = "Cleared"
        end

        % Value changed function: SentenceTextArea
        function SentenceTextAreaValueChanged(app, event)
            value = app.SentenceTextArea.Value;
            disp(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LoadnetButton = uibutton(app.UIFigure, 'push');
            app.LoadnetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadnetButtonPushed, true);
            app.LoadnetButton.Text = 'Load net';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image ';
            app.SegmentedImageLabel = uilabel(app.UIFigure);
            app.SegmentedImageLabel.Text = 'Segmented Image';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.CharacterTextAreaLabel = uilabel(app.UIFigure);
            app.CharacterTextAreaLabel.Text = 'Character';
            app.CharacterTextArea = uitextarea(app.UIFigure);
            app.CharacterTextArea.ValueChangedFcn = createCallbackFcn(app, @CharacterTextAreaValueChanged, true);
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SentenceTextAreaLabel = uilabel(app.UIFigure);
            app.SentenceTextAreaLabel.Text = 'Sentence';
            app.SentenceTextArea = uitextarea(app.UIFigure);
            app.SentenceTextArea.ValueChangedFcn = createCallbackFcn(app, @SentenceTextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end